classdef IDcolonies_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OutputIdentificationPanel      matlab.ui.container.Panel
        DiameterPlot                   matlab.ui.control.UIAxes
        PigmentationPlot               matlab.ui.control.UIAxes
        FormPlot                       matlab.ui.control.UIAxes
        MarginPlot                     matlab.ui.control.UIAxes
        NumberofcoloniesTextAreaLabel  matlab.ui.control.Label
        NumberofcoloniesTextArea       matlab.ui.control.TextArea
        IdentificationmapPanel         matlab.ui.container.Panel
        IdentificationImage            matlab.ui.control.UIAxes
        ClusteringmapPanel             matlab.ui.container.Panel
        ClusteringImage                matlab.ui.control.UIAxes
        RunClusterButton               matlab.ui.control.Button
        kEditFieldLabel                matlab.ui.control.Label
        kEditField                     matlab.ui.control.NumericEditField
        ImageinputPanel                matlab.ui.container.Panel
        InputImage                     matlab.ui.control.UIAxes
        LoadimageButton                matlab.ui.control.Button
        IdentificationinputsPanel      matlab.ui.container.Panel
        OutputEditFieldLabel           matlab.ui.control.Label
        OutputEditField                matlab.ui.control.EditField
        PlateEditFieldLabel            matlab.ui.control.Label
        PlateEditField                 matlab.ui.control.EditField
        DateEditFieldLabel             matlab.ui.control.Label
        DateEditField                  matlab.ui.control.EditField
        RunidentificationButton        matlab.ui.control.Button
        SizeEditFieldLabel             matlab.ui.control.Label
        SizeEditField                  matlab.ui.control.NumericEditField
        BrightThresholdEditFieldLabel  matlab.ui.control.Label
        BrightThresholdEditField       matlab.ui.control.NumericEditField
        DarkThresholdEditFieldLabel    matlab.ui.control.Label
        DarkThresholdEditField         matlab.ui.control.NumericEditField
        RunBatchIdentificationButton   matlab.ui.control.Button
        ColFeaturesColonyidentificationandclusteringLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            global image1
            global filename
            global pathname
            
            [filename, pathname] = uigetfile({'*.tif'}, 'File Selector');
            
            fullpathname = strcat(pathname, filename);
            
            image1 = imread(fullpathname);
            
            imshow(image1, 'Parent', app.InputImage)
        end

        % Button pushed function: RunidentificationButton
        function RunidentificationButtonPushed(app, event)
          
            
            global image1
            global filename
            global pathname
            global croppedImage
            global statsData
            global file
            global day
            global plate
            global plate_size
            global brightT
            global darkT


            %Input: day0, plate# and .tif file
            %This script identifies bacteria colonies in a petri dish. It generates a mask first to avoid the outer part of the plate. Later, 
            % segmentation and label identification allows to target even those colonies that are not completely circular. It retrieves a .jpg
            % file with the identifies colonies and a .mat file with a label, morphological and RGB-lab pixel information. 
        
            pixel_size=25.4/96;
        
            %Information required to generate a label
            day = app.DateEditField.Value; 
            %Replace plateN it with n counter
            plate = app.PlateEditField.Value;
            %Name of output file
            file = app.OutputEditField.Value;
            %Size of plate 0, small/ 1, big
            plate_size = double(app.SizeEditField.Value);
            
            %Threshold values
            brightT = double(app.BrightThresholdEditField.Value);
            darkT = double(app.DarkThresholdEditField.Value);

            %16-bit file, RGB range 0-2500
            %Turn into 8-bit, RGB range 0-255 with a simple division
            I = uint8(image1/257);
            
            %%
            %%Remove uninterested region from the image 
            %Get the image size to remove the rim of the petri dish
            imageSize = size(I);
            %center and radius of circle ([c_col, c_row, r]). I set my center at
            %[y0,x0] = [1040, 1015] and r = 845 for big plates and for
            %small plates r=510
            if plate_size ==  1
                ci = [1040, 1015, 840]; 
            else
                ci = [1040, 1015, 510];
            end
            %Make a grid the same dimensions as the original image
            [xx,yy] = ndgrid((1:imageSize(1))-ci(1),(1:imageSize(2))-ci(2));
            %Make a mask that will turn black all the area outside the plate by
            %fitting a circle with the size of the plate
            mask = uint8((xx.^2 + yy.^2)<ci(3)^2);
            %Generate the new image, cropped imaged after the mask is applied
            croppedImage = uint8(true(size(I)));
            croppedImage(:,:,1) = I(:,:,1).*mask;
            croppedImage(:,:,2) = I(:,:,2).*mask;
            croppedImage(:,:,3) = I(:,:,3).*mask;
        
        %%
            %The red channel was the most informative one, therefore for colony identification
            %I decided to only take information from the red channel
            
            %Correct non-uniform ilumination. Adjust specific range if needed
            rgbI = imadjust(croppedImage(:,:,1), [0 0.60],[]);
            
        %%
            %There are two types of colonies on the plates, ones with higher RGB values
            %than the background and some other with lower RGB values than the
            %background. I implemented a two-step process to identify all of them.
            
            %Filter bright colonies
            %rgbBW = rgbI >= 200;%imshow(rgbBW)
            rgbBW = rgbI >= brightT;
            %remove connected objects
            rgbI_nobord = imclearborder(rgbBW,8);%imshow(rgbI_nobord)
            %to fill up holes
            rgbI_noholes = imfill(rgbI_nobord,'holes');%imshow(rgbI_final)       
            %smooth object. Avoids extracting background information
            seD = strel('diamond',1);
            rgbI_final = imerode(rgbI_noholes,seD);
            
            
            %Find colonies using boundary.
            %B, returns an array of pixel locations
            %L, label matrix of objects
            %n, number of objects (labels)
            [B1,L1,n1] = bwboundaries(rgbI_final,'noholes');   
        
        %Remove comments if one wants to check the boundaries found
        %         figure
        %         imshow(I)
        %         hold on
        %         for k = 1:length(B)
        %            boundary = B{k};
        %            plot(boundary(:,2), boundary(:,1), 'r', 'LineWidth', 2)
        %         end
        
            %Filter dark colonies
            %rgbBW = rgbI < 50;%imshow(rgbBW)
            rgbBW = rgbI < darkT;%imshow(rgbBW)
            %remove connected objects
            rgbI_nobord = imclearborder(rgbBW,8);%imshow(rgbI_nobord)
            %rgbI_final = rgbI_nobord;
            %smooth object. Avoids extracting background information.
            seD = strel('diamond',1);
            rgbI_final = imerode(rgbI_nobord,seD);
        
            %Find colonies using boundary
            [B2,L2,n2] = bwboundaries(rgbI_final,'noholes');
        
            %Match both boundaries, most of the time no dark colonies will be
            %identify, therefor only the information from brigth colonies will be
            %used.
            if isempty(n2)
                %BW image
                B = B1;
                L = L1;
                n_colonies = n1;
            else
                %BW image
                B = [B1; B2];
                L = (L1 | L2);
                n_colonies = (n1+n2);
            end 
        
        %Remove if you want to print and save the BW image got from the boundaries
        %function
        %     figure
        %     imshow(L)
        %     print(strcat(filename{1},'-BW'),'-dpng');
        %     close;
        
            %get morphological stats
            stats=  regionprops(L, 'Centroid', 'Area', 'EquivDiameter', 'Perimeter', 'Circularity', 'Eccentricity', 'ConvexHull');
            Centroid = floor(cat(1,stats.Centroid));
            Eccentricity = cat(1,stats.Eccentricity);
            Area = cat(1,stats.Area);
            Diameter = cat(1,stats.EquivDiameter);
            
            %%
            %Get pixel information per channel RGB and Lab. Use the BW image generated using
            %boundaries
            %First turn RGB image to Lab
            labIm = rgb2lab(I);
            
            for k = 1:3
                rgbI = I(:,:,k);
                labI = labIm(:,:,k);
                %Save according to RGB channel
                if k == 1
                    %Better to have a different name for the different structures
                    statsPixel_red = regionprops(L,rgbI,{'Centroid','PixelValues','MeanIntensity'});
                    statsPixel_L = regionprops(L,labI,{'Centroid','PixelValues','MeanIntensity'});
                    %save(strcat(filename{1},'-pixel_red.mat'), 'statsPixel_red');
                elseif k == 2
                    %Better to have a different name for the different structures
                    statsPixel_green = regionprops(L,rgbI,{'Centroid','PixelValues', 'MeanIntensity'});
                    statsPixel_a = regionprops(L,labI,{'Centroid','PixelValues','MeanIntensity'});
                    %save(strcat(filename{1},'-pixel_green.mat'), 'statsPixel_green');
                else
                    %Better to have a different name for the different structures
                    statsPixel_blue = regionprops(L,rgbI,{'Centroid','PixelValues', 'MeanIntensity'});
                    statsPixel_b = regionprops(L,labI,{'Centroid','PixelValues','MeanIntensity'});
                    %save(strcat(filename{1},'-pixel_blue.mat'), 'statsPixel_blue');
                end
        
            end
            
            %%
            %Get edge information as peaks along the perimeter
            nh = length(stats);
            pks = zeros(nh, 1);
            h_pks = zeros(nh, 1);
            for i = 1:nh
                c = stats(i).Centroid;
                h = stats(i).ConvexHull;
                %Save the distance values
                n = length(h(:,1));
                d = zeros(n, 1);
                for j = 1:n
                    d(j) = pdist([c; h(j,:)],'euclidean');
                end
                %plot(d)
                %Curve smoothing
                x = 0:1:n-1;
                xx = 0:0.1:n-1;
                yy = spline(x, d, xx);
                %plot(x,y,'-',xx,yy)
                pks(i) = length(findpeaks(yy));
                h_pks(i) = mean(d);
            end
        
            %%
            %Filter 'bad quality data'
            %Filter by area bigger than n and eccentricity, the closes to 1 the more
            %line segment, the closes to 0 the more circular shape
            filter1 = find(Area > 200 & Area < 70000 & Eccentricity < 0.79); 
        
            %Find the elements close the the petri dish walls
            filter2 = zeros(length(filter1),1);
        
            %Calculate indexes to plot a circle and compare with the indexes in mask
            th = 0:pi/5:2*pi;
            for i = 1:length(filter1)
                %imshow(croppedImage);
                %Fit a circle
                xunit = floor(Diameter(filter1(i))/2 * cos(th) + Centroid(filter1(i),1));
                yunit = floor(Diameter(filter1(i))/2 * sin(th) + Centroid(filter1(i),2));
                %Find within the boundaries. Check ci variable
                mean_xy = mean(yunit>200 & yunit<1875 & xunit>200 & xunit<1875);
                if mean_xy == 1
                   filter2(i) = filter1(i);
                end
            end
        
            filter2 = nonzeros(filter2);
            
            %%
            %Get std on pixel values of the selected colonies
            std_red = zeros(length(filter2),1);
            std_green = zeros(length(filter2),1);
            std_blue = zeros(length(filter2),1);
            std_L = zeros(length(filter2),1);
            std_a = zeros(length(filter2),1);
            std_b = zeros(length(filter2),1);
            
            %Go to each of the colonies that past the two filters and get the std
            %values using the information from PixelValues
            for m = 1:length(filter2)
                std_red(m) = std(double(statsPixel_red(filter2(m)).PixelValues));
                std_green(m) = std(double(statsPixel_green(filter2(m)).PixelValues));
                std_blue(m) = std(double(statsPixel_blue(filter2(m)).PixelValues));  
                std_L(m) = std(double(statsPixel_L(filter2(m)).PixelValues));
                std_a(m) = std(double(statsPixel_a(filter2(m)).PixelValues));
                std_b(m) = std(double(statsPixel_b(filter2(m)).PixelValues));
            end
            RGB_std = [std_red, std_green, std_blue];
            Lab_std = [std_L, std_a, std_b];
        
            %%
            %Get data filtered 
            diameter = floor(Diameter(filter2));
            centroid = Centroid(filter2,:);
            area = cat(1,stats(filter2).Area);
            perimeter = cat(1,stats(filter2).Perimeter);
            circularity = cat(1,stats(filter2).Circularity);
            eccentricity = cat(1,stats(filter2).Eccentricity);
            R_mean = cat(1,statsPixel_red(filter2).MeanIntensity);
            G_mean = cat(1,statsPixel_green(filter2).MeanIntensity);
            B_mean = cat(1,statsPixel_blue(filter2).MeanIntensity);
            RGB_mean = [R_mean, G_mean, B_mean];
            L_mean = cat(1,statsPixel_L(filter2).MeanIntensity);
            a_mean = cat(1,statsPixel_a(filter2).MeanIntensity);
            b_mean = cat(1,statsPixel_b(filter2).MeanIntensity);
            Lab_mean = [L_mean, a_mean, b_mean];
            peaks = pks(filter2);
            h_peaks = h_pks(filter2);
            
            %%
            %Extract transversal information colony: mean and std
            %empty array to sabe data
            RGBt_mean = zeros(length(diameter), 3);
            RGBt_std = zeros(length(diameter), 3);
            Labt_mean = zeros(length(diameter), 3);
            Labt_std = zeros(length(diameter), 3);
            
            %Get x and y coordinates, x fixed
            for j = 1:length(diameter)
                x = floor(centroid(j,1)-diameter(j)/2:centroid(j,1)+diameter(j)/2);
                y = repmat(floor(centroid(j,2)), 1, length(x));
                c = improfile(I,x,y);
                cLab = improfile(labIm, x, y);
                %Read channel
                RGBt_mean(j,1) = mean(c(:,:,1));
                RGBt_std(j,1) = std(c(:,:,1));
                %L space
                Labt_mean(j,1) = mean(cLab(:,:,1));
                Labt_std(j,1) = std(cLab(:,:,1));
                %Green channel
                RGBt_mean(j,2) = mean(c(:,:,2));
                RGBt_std(j,2) = std(c(:,:,2));
                %a space
                Labt_mean(j,2) = mean(cLab(:,:,2));
                Labt_std(j,2) = std(cLab(:,:,2));
                %Blue channel
                RGBt_mean(j,3) = mean(c(:,:,3));
                RGBt_std(j,3) = std(c(:,:,3));
                %b space
                Labt_mean(j,3) = mean(cLab(:,:,3));
                Labt_std(j,3) = std(cLab(:,:,3));
            
            %Check transversal fixing y
            %     x = repmat(floor(centroid(j,1)), 1, length(x));
            %     y = floor(centroid(j,2)-diameter(1)/2:centroid(j,2)+diameter(1));
            %     cy = improfile(I,x,y);
        
                %Print intensity plots
            %     figure
            %     improfile(I,x,y); grid on
            end
            
            %%
            %%Plot the colonies found and save data
            %At this step labels are generated
            colony = strings(length(diameter),1); 
            label = strings(length(diameter),1);
            sample = strings(length(diameter),1);
            fileimage = split(string(filename), '.');
            s = fileimage(1);
            ID = strings(length(diameter),1);
        
            %Give labels
            for i = 1:length(diameter)
                colony(i) = int2str(i);
                label(i)= strcat(day,'-',plate,'-',int2str(i));
                %sample(i) = s{3};
                sample(i) = s;
                ID(i)=strcat(plate,'-',int2str(i));
            end
            
            %Get labels in place 
            f = figure('Visible',"off");
            imshow(croppedImage);
            viscircles(centroid,diameter/2,'Color','g');
            text(centroid(:,1), centroid(:,2)+diameter/2+25, colony, 'Color','black');
            outputimage = strcat(file,'-IDs');
            print(f, outputimage,'-dpng');
            
            %%
            %Show the output
            fullpathname = strcat(pathname, strcat(outputimage,'.png'));
            
            image2 = imread(fullpathname);
            imshow(image2, 'Parent', app.IdentificationImage)
            
            total_colonies = length(label);
            
            %Print total of colonies
            app.NumberofcoloniesTextArea.Value = string(total_colonies);
            
            %%Diameter plot
            diameter_plot = diameter*pixel_size;
            h = histogram(app.DiameterPlot, diameter_plot, 'FaceAlpha',1);
            %set x
            app.DiameterPlot.XLim = h.BinLimits;
            app.DiameterPlot.XTick = h.BinEdges;
            app.DiameterPlot.XLabel.String = 'Diameter (mm)';
            %set y axes
            max_y = max(h.BinCounts);
            app.DiameterPlot.YLim = [0 max_y];
            app.DiameterPlot.YTick = [0 max_y];
            app.DiameterPlot.YLabel.String = 'Count';
            %ylabel('Count');
            %set(gca,'fontsize',15)
            
            %%RGB color. Based on kmeans with k=3 using the RGBmean area
            %%data
            RGB_values = RGB_mean;
            %Defining 11 colors: 
            colors = [227 229 208;%white
                22 22 22;%black
                59 59 59;%grey
                50 43 13;%brown
                209 145 2;%yellow
                150 15 15;%red
                216 103 78;%orange
                128 127 3;%green
                100 149 171;%blue
                201 155 149;%pink
                97 35 50];%violet
            %nearest neightbour 
            idx = knnsearch(colors, RGB_values);
            
            count_color = [sum(idx==1), sum(idx==2), sum(idx==3), sum(idx==4)...
                sum(idx==5), sum(idx==6), sum(idx==7), sum(idx==8), sum(idx==9)...
                sum(idx==10), sum(idx==11)];
            %plot counts
            b = bar(app.PigmentationPlot, 1:11, count_color, 'FaceColor', 'flat');
            %bar color
            for k = 1:11
                b.CData(k,:) = colors(k,:)/255;
            end
            %y axes
            max_y = max(count_color);
            app.PigmentationPlot.YLim = [0 max_y];
            app.PigmentationPlot.YTick = [0 max_y];
            app.PigmentationPlot.YLabel.String = 'Count';

            
            %Circularity
            circle = circularity >= 1;
            type = categorical({'circular', 'irregular'});
            count_circle = [sum(circle), length(circularity) - sum(circle)];
            bar(app.FormPlot, type, count_circle)
            %y axes
            max_y = max(count_circle);
            app.FormPlot.YLim = [0 max_y];
            app.FormPlot.YTick = [0 max_y];
            app.FormPlot.YLabel.String = 'Count';
            
            %Margin
            margin = h_peaks >= 55;
            type = categorical({'entire', 'ondulate'});
            count_margin = [length(margin) - sum(margin), sum(margin)];
            bar(app.MarginPlot, type, count_margin)
            %y axes
            max_y = max(count_margin);
            app.MarginPlot.YLim = [0 max_y];
            app.MarginPlot.YTick = [0 max_y];
            app.MarginPlot.YLabel.String = 'Count';

            
            %Save data
            statsData = struct('label', label, 'sample', sample, 'ID', ID, 'centroid', centroid,...
                'area', area*pixel_size, 'diameter', diameter*pixel_size,'perimeter', perimeter*pixel_size, ...
                'peaks', peaks,'h_peaks', h_peaks,'circularity', circularity,'eccentricity', eccentricity, ...
                'RGB_mean', RGB_mean, 'RGB_std', RGB_std,'RGBt_mean', RGBt_mean, ...
                'RGBt_std', RGBt_std, 'Lab_mean', Lab_mean,'Lab_std', Lab_std, ...
                'Labt_mean', Labt_mean, 'Labt_std', Labt_std, 'RGB_cluster', idx, ...
                'circle', double(circle), 'entire', double(margin));
            %Save as .mat         
            save(strcat(file,'-data.mat'), 'statsData');
            %Save xls data
            tdata = struct2table(statsData);
            writetable(tdata, strcat(file,'-data.xls'))
            

        end

        % Button pushed function: RunBatchIdentificationButton
        function RunBatchIdentificationButtonPushed(app, event)
            %Inputs
            global day
            global brightT
            global darkT
            global plate_size
            
            
            pathfolder = uigetdir();
            fnames = dir(fullfile(pathfolder, '*tif'));
            numfids = length(fnames);

            %Go to each file
            for k = 1:numfids
                %Read the tif file
                plate =  num2str(k);
                file = strsplit(fnames(k).name,'.');
                %Run the desired ID function.
                IDcfu_Final_GUI(day, plate, file{1}, plate_size, brightT, darkT);
            end
        end

        % Callback function: RunClusterButton, kEditField
        function RunClusterButtonPushed(app, event)
            global pathname
            global file
            global statsData
            global croppedImage
            
            %Get k value given by user
            k = app.kEditField.Value;
            %Get data to cluster
            arrayfile = struct2array(statsData);
            X = double(arrayfile(:,9:36));
            %Cluster id
            idx = kmeans(X, double(k));

         
            % Centroids
            centroid1 = double(arrayfile(:,4));
            centroid2 = double(arrayfile(:,5));
            %diameter = double(arrayfile(:,7));
            
            %Get labels in place 
            f = figure('Visible',"off");
            imshow(croppedImage);
            viscircles([centroid1, centroid2], idx,'EdgeColor','w');
            text(centroid1, centroid2+30, string(idx));
            outputimage = strcat(file,'_clusters',string(k));
            print(f, outputimage,'-dpng');
%             
%             f = figure('Visible',"off");
%             imshow(croppedImage);
%             viscircles([centroid1,centroid2],idx,'EdgeColor','w');
%             text(centroid1, centroid2, string(idx));
%             outputimage = strcat(file,'_clusters',string(k));
%             print(f, outputimage,'-dpng');

            %Show the output
            fullpathname = strcat(pathname, strcat(outputimage, '.png'));
            
            image2 = imread(fullpathname);
            imshow(image2, 'Parent', app.ClusteringImage)
            
            %Write data output
            [statsData(:).cluster] = idx;
            %Save as .mat         
            save(strcat(file,'_clusters',string(k),'-data.mat'), 'statsData');
            %Save xls data
            tdata = struct2table(statsData);
            writetable(tdata, strcat(file,'_clusters',string(k),'-data.xls'))
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.OutputIdentificationPanel = uipanel(app.UIFigure);
            app.OutputIdentificationPanel.Title = 'Output Identification ';
            app.DiameterPlot = uiaxes(app.OutputIdentificationPanel);
            title(app.DiameterPlot, 'Diameter')
            xlabel(app.DiameterPlot, '')
            ylabel(app.DiameterPlot, '')
            app.DiameterPlot.XTick = [];
            app.DiameterPlot.YTick = [];
            app.PigmentationPlot = uiaxes(app.OutputIdentificationPanel);
            title(app.PigmentationPlot, 'Pigmentation')
            xlabel(app.PigmentationPlot, '')
            ylabel(app.PigmentationPlot, '')
            app.PigmentationPlot.XTick = [];
            app.PigmentationPlot.YTick = [];
            app.FormPlot = uiaxes(app.OutputIdentificationPanel);
            title(app.FormPlot, 'Form')
            xlabel(app.FormPlot, '')
            ylabel(app.FormPlot, '')
            app.FormPlot.XTick = [];
            app.FormPlot.YTick = [];
            app.MarginPlot = uiaxes(app.OutputIdentificationPanel);
            title(app.MarginPlot, 'Margin')
            xlabel(app.MarginPlot, '')
            ylabel(app.MarginPlot, '')
            app.MarginPlot.XTick = [];
            app.MarginPlot.YTick = [];
            app.NumberofcoloniesTextAreaLabel = uilabel(app.OutputIdentificationPanel);
            app.NumberofcoloniesTextAreaLabel.Text = 'Number of colonies';
            app.NumberofcoloniesTextArea = uitextarea(app.OutputIdentificationPanel);
            app.IdentificationmapPanel = uipanel(app.UIFigure);
            app.IdentificationmapPanel.Title = 'Identification map';
            app.IdentificationImage = uiaxes(app.IdentificationmapPanel);
            title(app.IdentificationImage, '')
            xlabel(app.IdentificationImage, '')
            ylabel(app.IdentificationImage, '')
            app.IdentificationImage.XTick = [];
            app.IdentificationImage.YTick = [];
            app.ClusteringmapPanel = uipanel(app.UIFigure);
            app.ClusteringmapPanel.Title = 'Clustering map';
            app.ClusteringImage = uiaxes(app.ClusteringmapPanel);
            title(app.ClusteringImage, '')
            xlabel(app.ClusteringImage, '')
            ylabel(app.ClusteringImage, '')
            app.ClusteringImage.XTick = [];
            app.ClusteringImage.YTick = [];
            app.RunClusterButton = uibutton(app.ClusteringmapPanel, 'push');
            app.RunClusterButton.ButtonPushedFcn = createCallbackFcn(app, @RunClusterButtonPushed, true);
            app.RunClusterButton.Text = 'Cluster';
            app.kEditFieldLabel = uilabel(app.ClusteringmapPanel);
            app.kEditFieldLabel.Text = 'k=';
            app.kEditField = uieditfield(app.ClusteringmapPanel, 'numeric');
            app.kEditField.Limits = [2 Inf];
            app.kEditField.ValueChangedFcn = createCallbackFcn(app, @RunClusterButtonPushed, true);
            app.kEditField.Value = 2;
            app.ImageinputPanel = uipanel(app.UIFigure);
            app.ImageinputPanel.Title = 'Image input';
            app.InputImage = uiaxes(app.ImageinputPanel);
            title(app.InputImage, '')
            xlabel(app.InputImage, '')
            ylabel(app.InputImage, '')
            app.InputImage.XTick = [];
            app.InputImage.YTick = [];
            app.LoadimageButton = uibutton(app.ImageinputPanel, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.IdentificationinputsPanel = uipanel(app.UIFigure);
            app.IdentificationinputsPanel.Title = 'Identification inputs';
            app.OutputEditFieldLabel = uilabel(app.IdentificationinputsPanel);
            app.OutputEditFieldLabel.Text = 'Output';
            app.OutputEditField = uieditfield(app.IdentificationinputsPanel, 'text');
            app.OutputEditField.Value = 'test';
            app.PlateEditFieldLabel = uilabel(app.IdentificationinputsPanel);
            app.PlateEditFieldLabel.Text = 'Plate #';
            app.PlateEditField = uieditfield(app.IdentificationinputsPanel, 'text');
            app.PlateEditField.Value = '1';
            app.DateEditFieldLabel = uilabel(app.IdentificationinputsPanel);
            app.DateEditFieldLabel.Text = 'Date';
            app.DateEditField = uieditfield(app.IdentificationinputsPanel, 'text');
            app.DateEditField.Value = 'dd-mm-yy';
            app.RunidentificationButton = uibutton(app.IdentificationinputsPanel, 'push');
            app.RunidentificationButton.ButtonPushedFcn = createCallbackFcn(app, @RunidentificationButtonPushed, true);
            app.RunidentificationButton.Text = 'Run identification';
            app.SizeEditFieldLabel = uilabel(app.IdentificationinputsPanel);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.IdentificationinputsPanel, 'numeric');
            app.SizeEditField.Limits = [0 1];
            app.SizeEditField.Value = 1;
            app.BrightThresholdEditFieldLabel = uilabel(app.IdentificationinputsPanel);
            app.BrightThresholdEditFieldLabel.Text = 'Bright Threshold';
            app.BrightThresholdEditField = uieditfield(app.IdentificationinputsPanel, 'numeric');
            app.BrightThresholdEditField.Limits = [0 250];
            app.BrightThresholdEditField.Value = 200;
            app.DarkThresholdEditFieldLabel = uilabel(app.IdentificationinputsPanel);
            app.DarkThresholdEditFieldLabel.Text = 'Dark Threshold';
            app.DarkThresholdEditField = uieditfield(app.IdentificationinputsPanel, 'numeric');
            app.DarkThresholdEditField.Limits = [0 250];
            app.DarkThresholdEditField.Value = 50;
            app.RunBatchIdentificationButton = uibutton(app.IdentificationinputsPanel, 'push');
            app.RunBatchIdentificationButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchIdentificationButtonPushed, true);
            app.RunBatchIdentificationButton.Text = 'Run Batch Identification';
            app.ColFeaturesColonyidentificationandclusteringLabel = uilabel(app.UIFigure);
            app.ColFeaturesColonyidentificationandclusteringLabel.Text = 'ColFeatures: Colony identification and clustering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IDcolonies_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end